classdef Electrospinner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TestTimeLabel                  matlab.ui.control.Label
        CV_drum_accelLabel             matlab.ui.control.Label
        CV_drum_speedLabel             matlab.ui.control.Label
        CV_o_speedLabel                matlab.ui.control.Label
        CV_o_distanceLabel             matlab.ui.control.Label
        CV_flow_rateLabel              matlab.ui.control.Label
        CurrentValueLabel              matlab.ui.control.Label
        DetectPortsButton              matlab.ui.control.Button
        ArduinoLabel                   matlab.ui.control.Label
        ArduinoPortDropDown            matlab.ui.control.DropDown
        ArduinoPortDropDownLabel       matlab.ui.control.Label
        ConnecttoArduinoButton         matlab.ui.control.Button
        EmergencyStopButton            matlab.ui.control.Button
        ErrorLabel                     matlab.ui.control.Label
        Drumrampupaccelerationrevs2EditField  matlab.ui.control.NumericEditField
        Drumrampupaccelerationrevs2EditFieldLabel  matlab.ui.control.Label
        OscillatorspeedcmminEditField  matlab.ui.control.NumericEditField
        OscillatorspeedcmminEditFieldLabel  matlab.ui.control.Label
        OscillationDistancefromCentercmEditField  matlab.ui.control.NumericEditField
        OscillationDistancefromCentercmEditFieldLabel  matlab.ui.control.Label
        CollectorSwitch                matlab.ui.control.Switch
        CollectorSwitchLabel           matlab.ui.control.Label
        DrumVelocityRPMEditField       matlab.ui.control.NumericEditField
        DrumVelocityRPMEditFieldLabel  matlab.ui.control.Label
        PolymerFlowRatemLhrEditField   matlab.ui.control.NumericEditField
        PolymerFlowRatemLhrEditFieldLabel  matlab.ui.control.Label
        EndTestButton                  matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        testTimer % tells user how long their test has been running
    end
    methods (Access = private)
        
        function Timer_fct(app, event)
            global timer_start
            time=timer_start;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup(app)
            %Declare global variables
            global ping_recieved
            global first_press
            ping_recieved=0;
            first_press=1;
            %Detect ports
            ports=serialportlist;
            app.ArduinoPortDropDown.Items=ports;

        end

        % Value changed function: CollectorSwitch
        function Collector_selection(app, event)
            global first_press
            value = app.CollectorSwitch.Value;
            if value=="Drum"
                app.DrumVelocityRPMEditField.Visible='on';
                app.DrumVelocityRPMEditFieldLabel.Visible='on';
                app.Drumrampupaccelerationrevs2EditField.Visible='on';
                app.Drumrampupaccelerationrevs2EditFieldLabel.Visible='on';
                if first_press==0
                    app.CV_drum_speedLabel.Visible='on';
                    app.CV_drum_accelLabel.Visible='on';
                end
            else
                app.DrumVelocityRPMEditField.Visible='off';
                app.DrumVelocityRPMEditFieldLabel.Visible='off';
                app.Drumrampupaccelerationrevs2EditField.Visible='off';
                app.Drumrampupaccelerationrevs2EditFieldLabel.Visible='off';
                if first_press==0
                    app.CV_drum_speedLabel.Visible='off';
                    app.CV_drum_accelLabel.Visible='off';
                end
            end
        end

        % Button pushed function: ApplyButton
        function send_values(app, event)
            global drum_on
            global first_press
            %Save values
            flow_rate=app.PolymerFlowRatemLhrEditField.Value();
            o_distance=app.OscillationDistancefromCentercmEditField.Value();
            o_speed=app.OscillatorspeedcmminEditField.Value();
            %Check if drum is selected
            if app.CollectorSwitch.Value=="Drum"
                drum_on=1;
                drum_speed=app.DrumVelocityRPMEditField.Value();
                drum_accel=app.Drumrampupaccelerationrevs2EditField.Value();
            else
                drum_on=0;
                drum_speed=0;
                drum_accel=0;
            end
            %Compile data
            data_array=[flow_rate, o_distance, o_speed, drum_speed, drum_accel];

            %Send data to arduino
            if first_press
                delete(instrfind({'Port'},{app.ArduinoPortDropDown.Value}))
            end
            
            %Wait for ping back
            waiting_period=1;
            ping_recieved=1; %CHANGE TO 0
            tic;
            while toc<waiting_period&&ping_recieved==0
                %Check for ping, set ping_recieved to 1
            end
            if ping_recieved
                %Change Current Value labels visibility
                if first_press
                    app.CurrentValueLabel.Visible="on";
                    app.CV_flow_rateLabel.Visible="on";
                    app.CV_o_distanceLabel.Visible="on";
                    app.CV_o_speedLabel.Visible="on";
                    if drum_on
                        app.CV_drum_speedLabel.Visible="on";
                        app.CV_drum_accelLabel.Visible="on";
                    end
                    first_press=0;

                end
                %Change Current Value labels
                app.CV_flow_rateLabel.Text=string(flow_rate);
                app.CV_o_distanceLabel.Text=string(o_distance);
                app.CV_o_speedLabel.Text=string(o_speed);
                app.CV_drum_speedLabel.Text=string(drum_speed);
                app.CV_drum_accelLabel.Text=string(drum_accel);

                %Turn on test time
                app.TestTimeLabel.Visible='on';
                app.TestTimeLabel

            else
                app.ArduinoLabel.Text="Arduino not detected";
                app.ArduinoLabel.FontColor="r";
                app.ArduinoLabel.Visible='on';
            end
        end

        % Button pushed function: EndTestButton
        function Stop(app, event)
            global ping_recieved
            global drum_on
            global first_press
            %Check if arduino is available
            
            ping_recieved=1; %DELETE
            if ping_recieved==0
                app.ArduinoLabel.Text="Arduino not detected";
                app.ArduinoLabel.FontColor="r";
                app.ArduinoLabel.Visible="on";
            else
                %Save values
                flow_rate=-2;
                o_distance=app.OscillationDistancefromCentercmEditField.Value();
                o_speed=0;
                %Check if drum is selected
                drum_speed=0;
                drum_accel=app.Drumrampupaccelerationrevs2EditField.Value();
                %Compile data
                data_array=[flow_rate, o_distance, o_speed, drum_speed, drum_accel];

                %Send data to arduino

                %Wait for ping back
                waiting_period=1;
                ping_recieved=1; %CHANGE TO 0
                tic;
                while toc<waiting_period&&ping_recieved==0
                    %Check for ping, set ping_recieved to 1
                end
                if ping_recieved
                    %Change Current Value labels visibility
                    if first_press
                        app.CurrentValueLabel.Visible="on";
                        app.CV_flow_rateLabel.Visible="on";
                        app.CV_o_distanceLabel.Visible="on";
                        app.CV_o_speedLabel.Visible="on";
                        if app.CollectorSwitch.Value=="Drum"
                            app.CV_drum_speedLabel.Visible="on";
                            app.CV_drum_accelLabel.Visible="on";
                        end
                        first_press=0;

                    end
                    %Change Current Value labels
                    app.CV_flow_rateLabel.Text="Resetting (-2 ml/s)";
                    app.CV_o_distanceLabel.Text=string(o_distance);
                    app.CV_o_speedLabel.Text=string(o_speed);
                    app.CV_drum_speedLabel.Text=string(drum_speed);
                    app.CV_drum_accelLabel.Text="Whatever it is";

                else
                    app.ArduinoLabel.Text="Arduino not detected";
                    app.ArduinoLabel.FontColor="r";
                    app.ArduinoLabel.Visible='on';
                end
            end
        end

        % Button pushed function: EmergencyStopButton
        function E_stop(app, event)
            global ping_recieved
            %Check if arudino is available
            if ping_recieved==0
                app.ArduinoLabel.Text="Arduino not detected";
                app.ArduinoLabel.FontColor="r";
                app.ArduinoLabel.Visible="on";
            end
        end

        % Button pushed function: DetectPortsButton
        function detect_ports(app, event)
            %Detect ports
            ports=serialportlist;
            app.ArduinoPortDropDown.Items=ports;
        end

        % Button pushed function: ConnecttoArduinoButton
        function ping_arduino(app, event)
            global ping_recieved
            %Ping arduino

            %Wait for ping back
            waiting_period=1;
            ping_recieved=0;
            tic;
            while toc<waiting_period&&ping_recieved==0
                %Check for ping, set ping_recieved to 1
            end
            if ping_recieved
                app.ArduinoLabel.Text="Arduino Connected";
                app.ArduinoLabel.FontColor="g";
            else
                app.ArduinoLabel.Text="Arduino not detected";
                app.ArduinoLabel.FontColor="r";
            end
            app.ArduinoLabel.Visible="on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @send_values, true);
            app.ApplyButton.Text = 'Apply';
            app.EndTestButton = uibutton(app.UIFigure, 'push');
            app.EndTestButton.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.EndTestButton.Text = 'End Test';
            app.PolymerFlowRatemLhrEditFieldLabel = uilabel(app.UIFigure);
            app.PolymerFlowRatemLhrEditFieldLabel.Text = 'Polymer Flow Rate (mL/hr)';
            app.PolymerFlowRatemLhrEditField = uieditfield(app.UIFigure, 'numeric');
            app.PolymerFlowRatemLhrEditField.Limits = [0 Inf];
            app.PolymerFlowRatemLhrEditField.Value = 1;
            app.DrumVelocityRPMEditFieldLabel = uilabel(app.UIFigure);
            app.DrumVelocityRPMEditFieldLabel.Visible = 'off';
            app.DrumVelocityRPMEditFieldLabel.Text = 'Drum Velocity (RPM)';
            app.DrumVelocityRPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.DrumVelocityRPMEditField.Visible = 'off';
            app.DrumVelocityRPMEditField.Value = 1000;
            app.CollectorSwitchLabel = uilabel(app.UIFigure);
            app.CollectorSwitchLabel.Text = 'Collector';
            app.CollectorSwitch = uiswitch(app.UIFigure, 'slider');
            app.CollectorSwitch.Items = {'Plate', 'Drum'};
            app.CollectorSwitch.ValueChangedFcn = createCallbackFcn(app, @Collector_selection, true);
            app.CollectorSwitch.Value = 'Plate';
            app.OscillationDistancefromCentercmEditFieldLabel = uilabel(app.UIFigure);
            app.OscillationDistancefromCentercmEditFieldLabel.Text = 'Oscillation Distance from Center (cm)';
            app.OscillationDistancefromCentercmEditField = uieditfield(app.UIFigure, 'numeric');
            app.OscillationDistancefromCentercmEditField.Limits = [0 Inf];
            app.OscillatorspeedcmminEditFieldLabel = uilabel(app.UIFigure);
            app.OscillatorspeedcmminEditFieldLabel.Text = 'Oscillator speed (cm/min)';
            app.OscillatorspeedcmminEditField = uieditfield(app.UIFigure, 'numeric');
            app.Drumrampupaccelerationrevs2EditFieldLabel = uilabel(app.UIFigure);
            app.Drumrampupaccelerationrevs2EditFieldLabel.Visible = 'off';
            app.Drumrampupaccelerationrevs2EditFieldLabel.Text = 'Drum ramp up acceleration (rev/s^2)';
            app.Drumrampupaccelerationrevs2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Drumrampupaccelerationrevs2EditField.Visible = 'off';
            app.Drumrampupaccelerationrevs2EditField.Value = 0.5;
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error Label';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @E_stop, true);
            app.EmergencyStopButton.Icon = 'Button_Icon_Red.png';
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ping_arduino, true);
            app.ConnecttoArduinoButton.Text = {'Connect to Arduino'; ''};
            app.ArduinoPortDropDownLabel = uilabel(app.UIFigure);
            app.ArduinoPortDropDownLabel.Text = 'Arduino Port';
            app.ArduinoPortDropDown = uidropdown(app.UIFigure);
            app.ArduinoLabel = uilabel(app.UIFigure);
            app.ArduinoLabel.Visible = 'off';
            app.ArduinoLabel.Text = 'Arduino Label';
            app.DetectPortsButton = uibutton(app.UIFigure, 'push');
            app.DetectPortsButton.ButtonPushedFcn = createCallbackFcn(app, @detect_ports, true);
            app.DetectPortsButton.Text = 'Detect Ports';
            app.CurrentValueLabel = uilabel(app.UIFigure);
            app.CurrentValueLabel.Visible = 'off';
            app.CurrentValueLabel.Text = 'Current Value';
            app.CV_flow_rateLabel = uilabel(app.UIFigure);
            app.CV_flow_rateLabel.Visible = 'off';
            app.CV_flow_rateLabel.Text = 'CV_flow_rate';
            app.CV_o_distanceLabel = uilabel(app.UIFigure);
            app.CV_o_distanceLabel.Visible = 'off';
            app.CV_o_distanceLabel.Text = 'CV_o_distance';
            app.CV_o_speedLabel = uilabel(app.UIFigure);
            app.CV_o_speedLabel.Visible = 'off';
            app.CV_o_speedLabel.Text = 'CV_o_speed';
            app.CV_drum_speedLabel = uilabel(app.UIFigure);
            app.CV_drum_speedLabel.Visible = 'off';
            app.CV_drum_speedLabel.Text = 'CV_drum_speed';
            app.CV_drum_accelLabel = uilabel(app.UIFigure);
            app.CV_drum_accelLabel.Visible = 'off';
            app.CV_drum_accelLabel.Text = 'CV_drum_accel';
            app.TestTimeLabel = uilabel(app.UIFigure);
            app.TestTimeLabel.Visible = 'off';
            app.TestTimeLabel.Text = 'Test Time';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Electrospinner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end